classdef piano_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PlayrandomnotesButton    matlab.ui.control.Button
        PlayHappyBirthdayButton  matlab.ui.control.Button
        FrequencyGauge           matlab.ui.control.LinearGauge
        FrequencyGaugeLabel      matlab.ui.control.Label
        Image4                   matlab.ui.control.Image
        Image3                   matlab.ui.control.Image
        Image                    matlab.ui.control.Image
        OctaveSlider             matlab.ui.control.Slider
        OctaveSliderLabel        matlab.ui.control.Label
        ASharp                   matlab.ui.control.Button
        GSharp                   matlab.ui.control.Button
        FSharp                   matlab.ui.control.Button
        DSharp                   matlab.ui.control.Button
        CSharp                   matlab.ui.control.Button
        B                        matlab.ui.control.Button
        A                        matlab.ui.control.Button
        G                        matlab.ui.control.Button
        F                        matlab.ui.control.Button
        E                        matlab.ui.control.Button
        D                        matlab.ui.control.Button
        C                        matlab.ui.control.Button
        PianoAppLabel            matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              clear all;
              global a;
              a = arduino("COM3", "MEGA2560"); % Description
        end

        % Button pushed function: C
        function CButtonPushed(app, event)
            global a;
            value = get(app.OctaveSlider, "value");
            playTone(a,"D9", 32.7*2^(value+1), 0.5);
            app.FrequencyGauge.Value = 32.7*2^(value+1);
        end

        % Button pushed function: D
        function DButtonPushed(app, event)
            global a;
            value = get(app.OctaveSlider, "value");
            playTone(a,"D9", 36.71*2^(value+1), 0.5);
            app.FrequencyGauge.Value = 36.71*2^(value+1);
        end

        % Button pushed function: E
        function EButtonPushed(app, event)
            global a;
            value = get(app.OctaveSlider, "value");
            playTone(a,"D9", 41.20*2^(value+1), 0.5);
            app.FrequencyGauge.Value = 41.20*2^(value+1);
        end

        % Button pushed function: F
        function FButtonPushed(app, event)
            global a;
            value = get(app.OctaveSlider, "value");
            playTone(a,"D9", 43.65*2^(value+1), 0.5);
            app.FrequencyGauge.Value = 43.65*2^(value+1);
        end

        % Button pushed function: G
        function GButtonPushed(app, event)
            global a;
            value = get(app.OctaveSlider, "value");
            playTone(a,"D9", 49.0*2^(value+1), 0.5);
            app.FrequencyGauge.Value = 49.0*2^(value+1);
        end

        % Button pushed function: A
        function AButtonPushed(app, event)
            global a;
            value = get(app.OctaveSlider, "value");
            playTone(a,"D9", 55.0*2^(value+1), 0.5);
            app.FrequencyGauge.Value = 55.0*2^(value+1);
        end

        % Button pushed function: B
        function BButtonPushed(app, event)
            global a;
            value = get(app.OctaveSlider, "value");
            playTone(a,"D9", 61.74*2^(value+1), 0.5);
            app.FrequencyGauge.Value = 61.74*2^(value+1);
        end

        % Button pushed function: CSharp
        function CSharpButtonPushed(app, event)
            global a;
            value = get(app.OctaveSlider, "value");
            playTone(a,"D9", 34.65*2^(value+1), 0.5);
            app.FrequencyGauge.Value = 34.65*2^(value+1);
        end

        % Button pushed function: DSharp
        function DSharpButtonPushed(app, event)
            global a;
            value = get(app.OctaveSlider, "value");
            playTone(a,"D9", 38.89*2^(value+1), 0.5);
            app.FrequencyGauge.Value = 38.89*2^(value+1);
        end

        % Button pushed function: FSharp
        function FSharpButtonPushed(app, event)
            global a;
            value = get(app.OctaveSlider, "value");
            playTone(a,"D9", 46.25*2^(value+1), 0.5);
            app.FrequencyGauge.Value = 46.25*2^(value+1);
        end

        % Button pushed function: GSharp
        function GSharpButtonPushed(app, event)
            global a;
            value = get(app.OctaveSlider, "value");
            playTone(a,"D9", 58.27*2^(value+1), 0.5);
            app.FrequencyGauge.Value = 58.27*2^(value+1);
        end

        % Button pushed function: ASharp
        function ASharpButtonPushed(app, event)
            global a;
            value = get(app.OctaveSlider, "value");
            playTone(a,"D9", 51.91*2^(value+1), 0.5);
            app.FrequencyGauge.Value = 51.91*2^(value+1);
        end

        % Value changed function: OctaveSlider
        function OctaveSliderValueChanged(app, event)
            value = app.OctaveSlider.Value;
        end

        % Button pushed function: PlayHappyBirthdayButton
        function PlayHappyBirthdayButtonPushed(app, event)
            global a;   
            playTone(a,"D9",131, 0.25);
            app.FrequencyGauge.Value = 131;
            pause(0.250);
            playTone(a,"D9",131, 0.25);
            pause(0.250);
            playTone(a,"D9",147, 0.25);
            app.FrequencyGauge.Value = 147;
            pause(0.500);
            playTone(a,"D9",131, 0.25);
            app.FrequencyGauge.Value = 131;
            pause(0.500);
            playTone(a,"D9",175, 0.25);
            app.FrequencyGauge.Value = 175;
            pause(0.500);
            playTone(a,"D9",165, 0.25);
            app.FrequencyGauge.Value = 165;
            pause(0.5000);
            playTone(a,"D9",131, 0.25);
            app.FrequencyGauge.Value = 131;
            pause(0.250);
            playTone(a,"D9",131, 0.25);
            pause(0.250);
            playTone(a,"D9",147, 0.25);
            app.FrequencyGauge.Value = 147;
            pause(0.500);
            playTone(a,"D9",131, 0.25);
            app.FrequencyGauge.Value = 131;
            pause(0.500);
            playTone(a,"D9",196, 0.25);
            app.FrequencyGauge.Value = 196;
            pause(0.500);
            playTone(a,"D9",175, 0.25);
            app.FrequencyGauge.Value = 175;
            pause(0.500);
            playTone(a,"D9",131, 0.25);
            app.FrequencyGauge.Value = 131;
            pause(0.250);
            playTone(a,"D9",131, 0.25);
            pause(0.250);
            playTone(a,"D9",262, 0.25);
            app.FrequencyGauge.Value = 262;
            pause(0.500);
            playTone(a,"D9",220, 0.25);
            app.FrequencyGauge.Value = 220;
            pause(0.500);
            playTone(a,"D9",175, 0.25);
            app.FrequencyGauge.Value = 175;
            pause(0.500);
            playTone(a,"D9",165, 0.25);
            app.FrequencyGauge.Value = 165;
            pause(0.500);
            playTone(a,"D9",147, 0.25);
            app.FrequencyGauge.Value = 147;
            pause(0.500);
            playTone(a,"D9",233, 0.25);
            app.FrequencyGauge.Value = 233;
            pause(0.250);
            playTone(a,"D9",233, 0.25);
            pause(0.250);
            playTone(a,"D9",220, 0.25);
            app.FrequencyGauge.Value = 220;
            pause(0.500);
            playTone(a,"D9",175, 0.25);
            app.FrequencyGauge.Value = 175;
            pause(0.500);
            playTone(a,"D9",196, 0.25);
            app.FrequencyGauge.Value = 196;
            pause(0.500);
            playTone(a,"D9",175, 0.25);
            app.FrequencyGauge.Value = 175;
            pause(0.1000);
        end

        % Callback function: not associated with a component
        function PlaySuperMarioBrosButtonPushed(app, event)
            global a;
            
            % doesn't play so well...

            NOTE_E7 = 2637;
            NOTE_C7 = 2093;
            NOTE_G7 = 3136;
            NOTE_G6 = 1568;
            NOTE_AS6 = 1865;
            NOTE_B6 = 1976;
            NOTE_E6 = 1319;
            NOTE_A6 = 1760;
            NOTE_A7 = 3520;
            NOTE_F7 = 2794;
            NOTE_D7 = 2349;

            playTone(a,"D9", NOTE_E7, 0.25);
            playTone(a,"D9", NOTE_E7, 0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_E7, 0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_C7, 0.25);
            playTone(a,"D9", NOTE_E7, 0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_G7, 0.25);
            pause(0.25);
            pause(0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_G6, 0.25);
            pause(0.25);
            pause(0.25);
            pause(0.25);
            
            playTone(a,"D9", NOTE_C7, 0.25);
            pause(0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_G6, 0.25);
            pause(0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_E6, 0.25);
            pause(0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_A6, 0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_B6, 0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_AS6, 0.25);
            playTone(a,"D9", NOTE_A6, 0.25);
            pause(0.25);
            
            playTone(a,"D9", NOTE_G6, 0.25);
            playTone(a,"D9", NOTE_E7, 0.25);
            playTone(a,"D9", NOTE_G7, 0.25);
            playTone(a,"D9", NOTE_A7, 0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_F7, 0.25);
            playTone(a,"D9", NOTE_G7, 0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_E7, 0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_C7, 0.25);
            playTone(a,"D9", NOTE_D7, 0.25);
            playTone(a,"D9", NOTE_B6, 0.25);
            pause(0.25);
            pause(0.25);
            
            playTone(a,"D9", NOTE_C7, 0.25);
            pause(0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_G6, 0.25);
            pause(0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_E6, 0.25);
            pause(0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_A6, 0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_B6, 0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_AS6, 0.25);
            playTone(a,"D9", NOTE_A6, 0.25);
            pause(0.25);
            
            playTone(a,"D9", NOTE_G6, 0.25);
            playTone(a,"D9", NOTE_E7, 0.25);
            playTone(a,"D9", NOTE_G7, 0.25);
            playTone(a,"D9", NOTE_A7, 0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_F7, 0.25);
            playTone(a,"D9", NOTE_G7, 0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_E7, 0.25);
            pause(0.25);
            playTone(a,"D9", NOTE_C7, 0.25);
            playTone(a,"D9", NOTE_D7, 0.25);
            playTone(a,"D9", NOTE_B6, 0.25);
            pause(0.25);
            pause(0.25);
        end

        % Button pushed function: PlayrandomnotesButton
        function PlayrandomnotesButtonPushed(app, event)
            global a;

            notes = [randi([30 8000],1,10)];

            [~,col] = size(notes);
            
            for i=1:col
                playTone(a,"D9",notes(i), 0.5);
                pause(0.5);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PianoAppLabel = uilabel(app.UIFigure);
            app.PianoAppLabel.Text = 'Piano App';
            app.C = uibutton(app.UIFigure, 'push');
            app.C.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.C.Text = '';
            app.D = uibutton(app.UIFigure, 'push');
            app.D.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.D.Text = '';
            app.E = uibutton(app.UIFigure, 'push');
            app.E.ButtonPushedFcn = createCallbackFcn(app, @EButtonPushed, true);
            app.E.Text = '';
            app.F = uibutton(app.UIFigure, 'push');
            app.F.ButtonPushedFcn = createCallbackFcn(app, @FButtonPushed, true);
            app.F.Text = '';
            app.G = uibutton(app.UIFigure, 'push');
            app.G.ButtonPushedFcn = createCallbackFcn(app, @GButtonPushed, true);
            app.G.Text = '';
            app.A = uibutton(app.UIFigure, 'push');
            app.A.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.A.Text = '';
            app.B = uibutton(app.UIFigure, 'push');
            app.B.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.B.Text = '';
            app.CSharp = uibutton(app.UIFigure, 'push');
            app.CSharp.ButtonPushedFcn = createCallbackFcn(app, @CSharpButtonPushed, true);
            app.CSharp.Text = ' ';
            app.DSharp = uibutton(app.UIFigure, 'push');
            app.DSharp.ButtonPushedFcn = createCallbackFcn(app, @DSharpButtonPushed, true);
            app.DSharp.Text = ' ';
            app.FSharp = uibutton(app.UIFigure, 'push');
            app.FSharp.ButtonPushedFcn = createCallbackFcn(app, @FSharpButtonPushed, true);
            app.FSharp.Text = ' ';
            app.GSharp = uibutton(app.UIFigure, 'push');
            app.GSharp.ButtonPushedFcn = createCallbackFcn(app, @GSharpButtonPushed, true);
            app.GSharp.Text = ' ';
            app.ASharp = uibutton(app.UIFigure, 'push');
            app.ASharp.ButtonPushedFcn = createCallbackFcn(app, @ASharpButtonPushed, true);
            app.ASharp.Text = ' ';
            app.OctaveSliderLabel = uilabel(app.UIFigure);
            app.OctaveSliderLabel.Text = 'Octave:';
            app.OctaveSlider = uislider(app.UIFigure);
            app.OctaveSlider.Limits = [1 6];
            app.OctaveSlider.ValueChangedFcn = createCallbackFcn(app, @OctaveSliderValueChanged, true);
            app.OctaveSlider.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'piano_logo2.jpeg');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'clé_fa.jpg');
            app.Image4 = uiimage(app.UIFigure);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'clé_sol.jpg');
            app.FrequencyGaugeLabel = uilabel(app.UIFigure);
            app.FrequencyGaugeLabel.Text = 'Frequency';
            app.FrequencyGauge = uigauge(app.UIFigure, 'linear');
            app.FrequencyGauge.Limits = [0 8000];
            app.PlayHappyBirthdayButton = uibutton(app.UIFigure, 'push');
            app.PlayHappyBirthdayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayHappyBirthdayButtonPushed, true);
            app.PlayHappyBirthdayButton.Text = 'Play Happy Birthday';
            app.PlayrandomnotesButton = uibutton(app.UIFigure, 'push');
            app.PlayrandomnotesButton.ButtonPushedFcn = createCallbackFcn(app, @PlayrandomnotesButtonPushed, true);
            app.PlayrandomnotesButton.Text = 'Play random notes';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = piano_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end